classdef First < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        NandinisreeLabel              matlab.ui.control.Label
        SimpleImageProcessingLabel    matlab.ui.control.Label
        ImageafternoiseremovalButton  matlab.ui.control.Button
        AddnoiseButton                matlab.ui.control.Button
        TakeanimageButton             matlab.ui.control.Button
        UIAxes3                       matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TakeanimageButton
        function TakeanimageButtonPushed(app, event)
            global pic;
            [filename, pathname] = uigetfile('*.*', 'Choose an Image');
            filename=strcat(pathname,filename);
            pic=imread(filename);
            imshow(pic,'Parent',app.UIAxes);
        end

        % Button pushed function: AddnoiseButton
        function AddnoiseButtonPushed(app, event)
            global pic;
            global blur;
            blur=pic;
            blur =imnoise(blur,'salt & pepper', 0.5);
            imshow(blur,'Parent',app.UIAxes2);
        end

        % Button pushed function: ImageafternoiseremovalButton
        function ImageafternoiseremovalButtonPushed(app, event)
            global blur;
            after=medfilt3(blur,[5,5,3]);
            imshow(after,'Parent',app.UIAxes3);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original image')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Noisy Image')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Image after removing noise')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.TakeanimageButton = uibutton(app.UIFigure, 'push');
            app.TakeanimageButton.ButtonPushedFcn = createCallbackFcn(app, @TakeanimageButtonPushed, true);
            app.TakeanimageButton.Text = 'Take an image';
            app.AddnoiseButton = uibutton(app.UIFigure, 'push');
            app.AddnoiseButton.ButtonPushedFcn = createCallbackFcn(app, @AddnoiseButtonPushed, true);
            app.AddnoiseButton.Text = 'Add noise';
            app.ImageafternoiseremovalButton = uibutton(app.UIFigure, 'push');
            app.ImageafternoiseremovalButton.ButtonPushedFcn = createCallbackFcn(app, @ImageafternoiseremovalButtonPushed, true);
            app.ImageafternoiseremovalButton.Text = 'Image after noise removal';
            app.SimpleImageProcessingLabel = uilabel(app.UIFigure);
            app.SimpleImageProcessingLabel.Text = 'Simple Image Processing ';
            app.NandinisreeLabel = uilabel(app.UIFigure);
            app.NandinisreeLabel.Text = 'Nandinisree';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = First

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end